classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        SignalpropertiesTab             matlab.ui.container.Tab
        SamplingfrequencyofsignalHzLabel  matlab.ui.control.Label
        Sampling_frequency_of_signal    matlab.ui.control.NumericEditField
        FrequencyofsignalHzLabel        matlab.ui.control.Label
        Frequency_of_signal             matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        NumberofperiodsofbaselengthEditFieldLabel  matlab.ui.control.Label
        NumberofperiodsofbaselengthEditField  matlab.ui.control.NumericEditField
        PlotsTab                        matlab.ui.container.Tab
        SignalCheckBox                  matlab.ui.control.CheckBox
        STFTCheckBox                    matlab.ui.control.CheckBox
        WaveletCheckBox                 matlab.ui.control.CheckBox
        FFTCheckBox                     matlab.ui.control.CheckBox
        STFTListBoxLabel                matlab.ui.control.Label
        STFTListBox                     matlab.ui.control.ListBox
        SignalbuilderTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        AddharmonicTab                  matlab.ui.container.Tab
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        NumberofharmonicEditFieldLabel  matlab.ui.control.Label
        NumberofharmonicEditField       matlab.ui.control.NumericEditField
        AddharmonicButton               matlab.ui.control.Button
        DeleteharmonicTab               matlab.ui.container.Tab
        NumberofharmonicLabel           matlab.ui.control.Label
        Number_of_harmonic              matlab.ui.control.DropDown
        DeleteharmonicButton            matlab.ui.control.Button
        AddsegmentTab                   matlab.ui.container.Tab
        add_segment                     matlab.ui.control.Button
        DeletesegmentTab                matlab.ui.container.Tab
        NumberofsegmentLabel            matlab.ui.control.Label
        Number_of_segment               matlab.ui.control.DropDown
        Delete_segment                  matlab.ui.control.Button
        DistortTab                      matlab.ui.container.Tab
        DistortionSliderLabel           matlab.ui.control.Label
        DistortionSlider                matlab.ui.control.Slider
        DistortButton                   matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        STFTTab                         matlab.ui.container.Tab
        OverlapLabel                    matlab.ui.control.Label
        Overlap                         matlab.ui.control.Slider
        WindowsizeLabel                 matlab.ui.control.Label
        Windowsize                      matlab.ui.control.Slider
        Overlap_fixed_message           matlab.ui.control.Label
        CWTTab                          matlab.ui.container.Tab
        VoicesperoctaveLabel            matlab.ui.control.Label
        VoicesperOctave                 matlab.ui.control.Slider
        TypeofWaveletListBoxLabel       matlab.ui.control.Label
        TypeofWaveletListBox            matlab.ui.control.ListBox
        SaveTab                         matlab.ui.container.Tab
        SavesignalasEditFieldLabel      matlab.ui.control.Label
        SavesignalasEditField           matlab.ui.control.EditField
        SavesignalButton                matlab.ui.control.Button
        SignalsavedLabel                matlab.ui.control.Label
        LoadsignalTab                   matlab.ui.container.Tab
        ListofsignalsLabel              matlab.ui.control.Label
        ListBoxSignals                  matlab.ui.control.ListBox
        LoadsignalButton                matlab.ui.control.Button
        SignalAxes                      matlab.ui.control.UIAxes
        FFTAxes                         matlab.ui.control.UIAxes
        STFTAxes                        matlab.ui.control.UIAxes
        CWTAxes                         matlab.ui.control.UIAxes
        CurrentsegmentEditFieldLabel    matlab.ui.control.Label
        CurrentsegmentEditField         matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

  
    properties (Access = public)
        i = 1;              %index that is used for navigation between segments
        j = 1;              % index that is used during deleting segments
        har_amp = [];       % 3d matrix used for containing information about harmonics and their amplitude.
                                % Consists of rows, columns and slices. Each slice
                                % represents each segment of signal and has two rows:
                                % first one for numbers of harmonics in signal,
                                % second one for their amplitude.
       segments = 1;        % Vector that stores all number of segments of signal. When program starts it has
                                % one segment.
        list_har =[];       % Vector used for displaying list of harmonic in drop down list ,,Number_of_harmonic". 
        list_segm = [];     % Vector used for displaying list of segments in drop down list ,,Number_of_segment".
        signal_segm = [];   % Vector used for cointaining signal in separete columns where each column states
                                % for each segment of signal 
        S = [];             %STFT output
        T = [];             %STFT output
        F = [];             %STFT output
        P = [];             %STFT output
        cfs = [];
        f_CWT= [];
        signal = [];        %vector used for storing signal ready to analyse
        lg;                 %length of signal [samples]
        main_folder;        %Variable for storing main folder path
        f;                  %Frequency of signal [Hz]
        fs;                 %Sampling frequency of signal [Hz]
        fr;                 %Frequency domain for FFT [Hz]
        %Values imported from Parameters
        base_k;
        STFT_View_x; 
        STFT_View_y;
        STFT_Window_Value;
        STFT_Overlap_Value;
        STFT_Overlap_Value_Surface;
        STFT_Window_Value_Surface;
    end
    
    methods (Access = private)
%%%%%%%%%%%%%%  Function for ploting signal %%%%%%%%%%%%%%%%%        
        function plot_signal(app)
            time = 0:1000/app.fs:1000*(app.lg-1)/app.fs;
            plot(app.SignalAxes,time,app.signal,'-r');                                     % plot signal in Axis box
            app.SignalAxes.YLabel.String = 'Amplitude';
            app.SignalAxes.XLabel.String = 'Time [ms]';
            app.SignalAxes.XGrid = 'on';
            app.SignalAxes.YGrid = 'on';
            axis(app.SignalAxes, 'tight');
        end
%%%%%%%%%%%%%%  Function for calculating and ploting Short Time Fourier Transform (STFT) %%%%%%%%%%%%%%%%%         
        function STFT(app)
            winsize = app.Windowsize.Value;                                   % size of the spectrogram window 
            overlap = app.Overlap.Value;                                      % size of the spectrogram overlap
            if winsize >= 0 && winsize<= 1                                    %those constant values are requiered
                winsize = 2;
            end
            if overlap >= 99 && overlap<= 100
                overlap = 99;
            end
            
            if strcmp(app.STFTListBox.Value,'Image') 
                [app.S,app.F,app.T,app.P] = spectrogram(app.signal,ceil(app.lg*winsize/100),floor(overlap/100*app.lg*winsize/100),256,app.fs);
                imagesc(app.STFTAxes, app.T, app.F,abs(app.S) );
                app.STFTAxes.View = [app.STFT_View_x app.STFT_View_y];
                app.STFTAxes.YLim = [0 (app.fs/2)];
                app.STFTAxes.YLabel.String = 'Frequency [Hz]';
                axis(app.STFTAxes, 'tight');
                app.STFTAxes.XLabel.String = 'Time [ms]';
                h = colorbar(app.STFTAxes);
                h.Label.String = 'Power/Frequency [db/Hz]';
                h.Label.FontSize = 10;
                h.AxisLocation = 'in';
            elseif strcmp(app.STFTListBox.Value,'Surface')
                [app.S,app.F,app.T,app.P] = spectrogram(app.signal,ceil(app.lg*winsize/100),floor(overlap/100*app.lg*winsize/100),256,app.fs);
                 surf(app.STFTAxes, app.T, app.F,abs(app.S) );
            end
        end
%%%%%%%%%%%%%%  Function for calculating and ploting Continous Wavelet Transform (CWT) %%%%%%%%%%%%%%%%%         
        function CWT(app)
            app.lg = length(app.signal);
            vpo = app.VoicesperOctave.Value;
            type = app.TypeofWaveletListBox.Value;
            time = 0:1000/app.fs:1000*(app.lg-1)/app.fs;
            [app.cfs,app.f_CWT] = cwt_calculation(app.signal,app.fs,vpo,type);
            contour(app.CWTAxes,time,app.f_CWT,abs(app.cfs).^2); 
             axis(app.CWTAxes, 'tight');
             g = colorbar(app.CWTAxes);
             g.Label.String = 'Power/Frequency [db/Hz]';
             g.Label.FontSize = 10;
             g.AxisLocation = 'in';
             app.CWTAxes.YLabel.String = 'Frequency [Hz]';
             app.CWTAxes.XLabel.String = 'Time [ms]'; 
             app.CWTAxes.XGrid = 'on';
             app.CWTAxes.YGrid = 'on';
        end
%%%%%%%%%%%%%%  Function for calculating and ploting Fast Fourier Transform (FFT) %%%%%%%%%%%%%%%%%         
        function FFT_calc(app)
             app.fr = app.fs*(0:(app.lg/2))/app.lg;       %Define the frequency domain f
             plot(app.FFTAxes,app.fr,fft_calculate(app.signal,app.lg),'-r');
             app.FFTAxes.YLabel.String = 'Amplitude';
             app.FFTAxes.XLabel.String = 'Frequency [Hz]';
             app.FFTAxes.XGrid = 'on';
             app.FFTAxes.YGrid = 'on';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %State of application at the start    
            movegui(app.UIFigure,"center");
            %Hide most of the tabs and elements
            app.CurrentsegmentEditField.Editable = 'off';
            app.UIFigure.Resize = 'off';
            app.CurrentsegmentEditField.Visible = 'off';
            app.CurrentsegmentEditFieldLabel.Visible = 'off';
            app.STFTListBox.Visible = 'off';
            app.STFTListBoxLabel.Visible = 'off';
            app.DistortButton.Visible = 'off';
            app.SignalbuilderTab.Parent = [];
            app.Overlap_fixed_message.Visible = 'off';
            app.AnalysisTab.Parent = [];
            app.PlotsTab.Parent = [];
            app.SaveTab.Parent = [];
            app.SignalsavedLabel.Visible = 'off';
            %Set the starting values of drop down list as empty string
            app.Number_of_segment.Items = {};
            app.Number_of_harmonic.Items = {}; 
            %Set current loaction and take name files of signals to list of
            %saved signals
            app.main_folder = pwd;
            names = extractfield(dir("Signals"),'name');
            if isempty(names(3:end))
                app.LoadsignalButton.Visible = 'off';
            else
                app.LoadsignalButton.Visible = 'on';
            end
            app.ListBoxSignals.Items = names(3:end);
            %Imported from Parametres
            app.STFT_View_x = evalin('base', 'STFT_View_x'); 
            app.STFT_View_y = evalin('base', 'STFT_View_y');
            app.STFT_Overlap_Value_Surface = evalin('base', 'STFT_Overlap_Value_Surface');
            app.STFT_Window_Value_Surface = evalin('base', 'STFT_Window_Value_Surface');
            %Set the starting values of sliders and textbox that displays
            %the current segment
            app.Windowsize.Value = evalin('base', 'STFT_Window_Value');
            app.Overlap.Value = evalin('base', 'STFT_Overlap_Value');
            app.CurrentsegmentEditField.Value = app.i;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {755, 755};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1170, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AddharmonicButton
        function AddharmonicButtonPushed(app, event)
            %What is going on after Addharmonic button is pressed:
            %1.Take values from Amplitude and Number of harmonic edit fields
            %2.Actualize list of harmonics
            %3.Calculate and plot signal
            %4.STFT
            %5.Continous Wavelet Transform (CWT)
            %6.FFT
            %7.Show some hidden tabs and objects
            
            %1.Take values from Amplitude and Number of harmonic edit fields
            if app.NumberofharmonicEditField<=0||app.AmplitudeEditField<=0
                fig = uifigure;
                uialert(fig,'Data error','Error');
            else
           
            app.har_amp(1,end+1,app.i) = app.NumberofharmonicEditField.Value; % Take the value from ,,Number_of_harmonic"                                                               % edit textbox and save it in new cell in har_amp matrix.
            app.har_amp(2,end,app.i) = app.AmplitudeEditField.Value;          % Take the value from ,,Amplitude"
            amp = app.har_amp(2,:,app.i);                                     % split har_amp on har and amp
            har = app.har_amp(1,:,app.i);                                     % split har_amp on har and amp
            %2.Actualize list of harmonics
            app.list_har = reshape(nonzeros(har),1,[]);                       % create list of harmonics from har omiting zeros
            app.Number_of_harmonic.Items =string(app.list_har);               % pass list of harmonics to drop down list ,,Number of harmonic"
            %3.Plot signal
            app.signal_segm(:,app.i) = create_signal(amp,app.f,app.fs,har,app.base_k);     % calculate current segment of signal with new harmonic
            app.signal = reshape(app.signal_segm,1,[]);                       % turn all segments of a signal into one vector
            app.lg = length(app.signal);                                      % length of signal
            plot_signal(app); 
            %4.STFT
            STFT(app);
            %5.Continous Wavelet Transform (CWT)
            CWT(app); 
            %6.FFT
            FFT_calc(app);
            %7.Show some hidden tabs and objects
            app.STFTListBox.Visible = 'on';                                  % unhide ListBox for changing type of STF plot
            app.STFTListBoxLabel.Visible = 'on';
            app.DistortButton.Visible = 'on';
            app.AnalysisTab.Parent = app.TabGroup;                           % unhide Analysis tab on menu
            app.SaveTab.Parent = app.TabGroup;                               % unhide Save tab on menu
            end
        end

        % Button pushed function: DeleteharmonicButton
        function DeleteharmonicButtonPushed(app, event)
            %What is going on after Deleteharmonic button is pressed
            %1.Delete one chosen harmonic from list of harmonics
            %2.Calculate and plot signal
            %3.STFT
            %4.Continous Wavelet Transform (CWT)
            %5.FFT
            
            %1.Delete one chosen harmonic from list of harmonics
            num_har = app.Number_of_harmonic.Value;                           % number of harmonic that will be deleted
            index_to_remove=find(app.har_amp(1,:,app.i)==str2double(num_har));% finding index of harmonic that will be deleted
            app.har_amp(:,index_to_remove,app.i) = 0;                         % to keep rectangular form of matrix harmonic is replaced with zero
            amp = app.har_amp(2,:,app.i);                                     % split har_amp on har and amp
            har = app.har_amp(1,:,app.i);                                     % split har_amp on har and amp
            app.list_har = reshape(nonzeros(app.har_amp(1,:,app.i)),1,[]);    % create list of harmonics from har omiting zeros
            app.Number_of_harmonic.Items = string(app.list_har);              % pass list of harmonics to drop down list ,,Number of harmonic
            %2.Calculate and plot signal
            app.signal_segm(:,app.i) = create_signal(amp,app.f,app.fs,har,app.base_k);     % calculate current segment of signal without deleted harmonic
            app.signal = reshape(app.signal_segm,1,[]);                       % turn all segments of a signal into one vector
            app.lg = length(app.signal);                                      % length of signal
            plot_signal(app);
            %3.STFT
            STFT(app);
            %4.Continous Wavelet Transform (CWT)
            CWT(app);
            %5.FFT
            FFT_calc(app);
        end

        % Button pushed function: add_segment
        function add_segmentPushed(app, event)
            %What is going on after add_segment button is pressed:
            %1.Adding new element to vector of segments
            %2.Adding new slice to harm_amp 3d matrix
            %3.Updating drop down list ,,Number_of_segments".
            
            %1.Adding new element to vector of segments
            app.i = app.i + 1;                                                % incrementation of index which states for number of segments
            app.segments(1,end+1) = app.i;                                    % add index value at the end of the segment vector
            %2.Adding new slice to har_amp 3d matrix
            app.har_amp(:,:,app.i) = 0;
            %3.Updating drop down list ,,Number_of_segments".
            app.list_segm = reshape(nonzeros(app.segments(1,:)),1,[]);        % create list of segments omiting zeros
            app.Number_of_segment.Items =string(app.list_segm);               % pass list of segments to drop down list ,,Number of segment"
            app.Number_of_harmonic.Items ={};                                 % clear the drop down list ,,Number of harmonic" for new segment
            app.CurrentsegmentEditField.Value = app.i;                        % change the current value in drop down list ,,Number of segment" to new segment
        end

        % Button pushed function: Delete_segment
        function Delete_segmentPushed(app, event)
            %What is going on after Delete_segment button is pressed:
            %1.Delete one chosen segment of signal
            %2.Calculate and plot signal
            %3.Actualize list of harmonics of current segment
            %4.Actualize list of segments of signal
            %5.STFT
            %6.Continous Wavelet Transform (CWT)
            %7.FFT
            %1.Delete one chosen segment of signal
            num_seg = app.Number_of_segment.Value;                            % number of segment that will be deleted
            app.signal_segm(:,str2double(num_seg)) = [];                      % deleting segment of signal 
            app.segments(1,find(app.segments==str2num(num_seg))) = 0;         % deleting index of deleted segment
            app.i = app.i-1;                                                  % decrementation of index which states for number of segments
            %2.Calculate and plot signal
            app.signal = reshape(app.signal_segm,1,[]);  
            app.lg = length(app.signal);                                      % length of signal
            plot_signal(app);
            %3.Actualize list of harmonics of current segment
            har = app.har_amp(1,:,app.i);
            app.list_har = reshape(nonzeros(har),1,[]);                       % create list of harmonics from har omiting zeros
            app.Number_of_harmonic.Items = string(app.list_har);              % pass list of harmonics to drop down list ,,Number of harmonic"
            %4.Actualize list of segments of signal
            app.list_segm = reshape(nonzeros(app.segments(1,:)),1,[]);        % create list of segments omiting zeros
            app.j = 1;
            while app.j<=length(app.list_segm)                                % fill the list of segments with new values from 1 to n where n states for lenght of segment vector
                app.list_segm(1,app.j) = app.j;
                app.j = app.j +1;
            end
            app.Number_of_segment.Items =string(app.list_segm);               % pass list of segments to drop down list ,,Number of segment"               
            app.CurrentsegmentEditField.Value = app.i; 
            %5.STFT
            STFT(app);
            %6.Continous Wavelet Transform (CWT)
            CWT(app);
            %7.FFT
            FFT_calc(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %What is going on after ApplyButton is pressed:
            
            app.f = app.Frequency_of_signal.Value;                            % frequency of signal
            app.fs = app.Sampling_frequency_of_signal.Value;                  % sampling frequency  signal
            app.base_k = app.NumberofperiodsofbaselengthEditField.Value;
            if app.f<=0||app.fs<=0||app.base_k<=0
                fig = uifigure;
                uialert(fig,'Data error','Error');
            elseif app.fs<4*app.f
                fig = uifigure;
                uialert(fig,'Sampling frequency must be at least five times larger than base frequency of a signal','Error');
            else
                app.SignalbuilderTab.Parent = app.TabGroup;
                app.PlotsTab.Parent = app.TabGroup;
                app.TabGroup.SelectedTab = app.SignalbuilderTab;
                app.ApplyButton.Visible = 'off';
                app.Sampling_frequency_of_signal.Editable = 'off';
                app.Frequency_of_signal.Editable = 'off';
                app.NumberofperiodsofbaselengthEditField.Editable = 'off';
                app.CurrentsegmentEditField.Visible = 'on';
                app.CurrentsegmentEditFieldLabel.Visible = 'on';
            end
            
        end

        % Value changed function: Windowsize
        function WindowsizeValueChanged(app, event)
            %Slider used for changing windowsize value for STFT
            STFT(app); 
        end

        % Value changed function: Overlap
        function OverlapValueChanged(app, event)
             %Slider used for changing overlap value for STFT
            STFT(app);
        end

        % Value changed function: VoicesperOctave
        function VoicesperOctaveValueChanged(app, event)
           %Slider used for changing value of number of voices per octave for CWT. 
           %Slider allows user to use only whole numbers. 
           
            value = app.VoicesperOctave.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            CWT(app);
        end

        % Button pushed function: DistortButton
        function DistortButtonPushed(app, event)
            %What is going on after Distort button is pressed:
            %1.Add distortion to signal
            %2.Plot signal
            %3.STFT
            %4.Continous Wavelet Transform (CWT)
            %5.FFT
            
            %1.Add distortion to signal
            app.lg = length(app.signal);                                                            % length of signal
            distortion_level = max(app.signal)*app.DistortionSlider.Value/100;                      % take the value from distortion slider
                                                                                                        % and scale this value with respect to amplitude of signal
            app.signal_segm(:,app.i) = signal_distort(app.signal_segm(:,app.i),distortion_level);   % distort signal
            app.signal = reshape(app.signal_segm,1,[]);  
            %2.Plot signal
            plot_signal(app);
            %3.STFT
            STFT(app); 
            %4.Continous Wavelet Transform (CWT)
            CWT(app);
            %5.FFT
            FFT_calc(app);
        end

        % Value changed function: TypeofWaveletListBox
        function TypeofWaveletListBoxValueChanged(app, event)
            %Choosing type of wavelet
            CWT(app);
        end

        % Value changed function: SignalCheckBox
        function SignalCheckBoxValueChanged(app, event)
  
            %Turning on/off visibility of signal plot
             
            if strcmp(app.SignalAxes.Visible, 'on')
                app.SignalAxes.Visible = 'off';
                set( findobj(app.SignalAxes.Children), 'Visible', 'off')
            else
                app.SignalAxes.Visible = 'on';
                set( findobj(app.SignalAxes.Children), 'Visible', 'on')
            end
        end

        % Value changed function: STFTCheckBox
        function STFTCheckBoxValueChanged(app, event)
            %Turning on/off visibility of STFT plot
            
            if strcmp(app.STFTAxes.Visible, 'on')
                app.STFTAxes.Visible = 'off';
                set( findobj(app.STFTAxes.Children), 'Visible', 'off')
                colorbar(app.STFTAxes,'hide');
                app.STFTListBox.Visible = 'off';
                app.STFTListBoxLabel.Visible = 'off';
            else
                app.STFTAxes.Visible = 'on';
                set( findobj(app.STFTAxes.Children), 'Visible', 'on')
                colorbar(app.STFTAxes);
                app.STFTListBox.Visible = 'on';
                app.STFTListBoxLabel.Visible = 'on';
            end
        end

        % Value changed function: WaveletCheckBox
        function WaveletCheckBoxValueChanged(app, event)
           %Turning on/off visibility of CWT plot
           
            if strcmp(app.CWTAxes.Visible, 'on')
                app.CWTAxes.Visible = 'off';
                set( findobj(app.CWTAxes.Children), 'Visible', 'off')
                colorbar(app.CWTAxes,'hide');
            else
                app.CWTAxes.Visible = 'on';
                set( findobj(app.CWTAxes.Children), 'Visible', 'on')
                colorbar(app.CWTAxes);
            end
        end

        % Value changed function: FFTCheckBox
        function FFTCheckBoxValueChanged(app, event)
           %Turning on/off visibility of FFT plot
           
            if strcmp(app.FFTAxes.Visible, 'on')
                app.FFTAxes.Visible = 'off';
                set( findobj(app.FFTAxes.Children), 'Visible', 'off')
            else
                app.FFTAxes.Visible = 'on';
                set( findobj(app.FFTAxes.Children), 'Visible', 'on')
            end
        end

        % Value changed function: STFTListBox
        function STFTListBoxValueChanged(app, event)
            %Changing type of STFT plot 
        
            value = app.STFTListBox.Value;
            app.lg = length(app.signal);
            %Surface type
            if strcmp(value,'Surface')
                app.Overlap.Visible = 'off';       %disable the overlap slider
                app.OverlapLabel.Visible = 'off';
                app.Overlap.Value = app.STFT_Overlap_Value_Surface;
                app.Windowsize.Value = app.STFT_Window_Value_Surface;
                app.Overlap_fixed_message.Visible = 'on';
            %Image type (colorful stripes)    
            elseif strcmp(value,'Image')
                app.Overlap.Visible = 'on';
                app.OverlapLabel.Visible = 'on';
                app.Overlap_fixed_message.Visible = 'off';
            end
            STFT(app);
        end

        % Button pushed function: SavesignalButton
        function SavesignalButtonPushed(app, event)
            save_as = app.SavesignalasEditField.Value;
            %signal = reshape(app.signal_segm,1,[]);
            structure.signal = app.signal;
            structure.fs = app.fs;
            structure.f = app.f;
            app.lg = length(app.signal);
            structure.lg = app.lg;
            structure.base_k = app.base_k;
            cd Signals
            save([save_as '.mat'], 'structure');
            names = extractfield(dir,'name');
            if isempty(names(3:end))
                app.LoadsignalButton.Visible = 'off';
            else
                app.LoadsignalButton.Visible = 'on';
            end
            app.ListBoxSignals.Items = names(3:end);
            app.SignalsavedLabel.Visible = 'on';
            cd (app.main_folder)
        end

        % Button pushed function: LoadsignalButton
        function LoadsignalButtonPushed(app, event)
            %What is going on after Delete_segment button is pressed:
            %1.Create a structure that contains sampling frequency,
                %frequency of basic harmonic and signal 
            %2.Plot signal
            %3.STFT
            %4.Continous Wavelet Transform (CWT)
            %5.FFT
            %6.Show some hidden tabs and objects and hide some others
            
            %1.Create a structure that contains sampling frequency,
                %frequency of basic harmonic and signal 
            cd Signals
            file = app.ListBoxSignals.Value;
            structure = load(file);
            app.fs = structure.structure.fs;
            app.f = structure.structure.f;
            app.signal_segm = structure.structure.signal;
            app.lg = structure.structure.lg;
            app.base_k = structure.structure.base_k;
            cd (app.main_folder)
            %2.Plot signal
            app.signal = reshape(app.signal_segm,1,[]);                               
            app.lg = length(app.signal);   
            plot_signal(app);
            %3.STFT
            STFT(app);
            %4.Continous Wavelet Transform (CWT)
            CWT(app);
            %5.FFT
            FFT_calc(app);
            %6.Show some hidden tabs and objects
            app.STFTListBox.Visible = 'on';
            app.STFTListBoxLabel.Visible = 'on';
            app.AnalysisTab.Parent = app.TabGroup;
            app.PlotsTab.Parent = app.TabGroup;
            %and hide some others
            app.ApplyButton.Visible = 'off';
            app.Sampling_frequency_of_signal.Editable = 'off';
            app.Frequency_of_signal.Editable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UITable2 = uitable(app.LeftPanel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SignalpropertiesTab = uitab(app.TabGroup);
            app.SignalpropertiesTab.Title = 'Signal properties';
            app.SamplingfrequencyofsignalHzLabel = uilabel(app.SignalpropertiesTab);
            app.SamplingfrequencyofsignalHzLabel.Text = 'Sampling frequency of signal [Hz]';
            app.Sampling_frequency_of_signal = uieditfield(app.SignalpropertiesTab, 'numeric');
            app.FrequencyofsignalHzLabel = uilabel(app.SignalpropertiesTab);
            app.FrequencyofsignalHzLabel.Text = 'Frequency of signal [Hz]';
            app.Frequency_of_signal = uieditfield(app.SignalpropertiesTab, 'numeric');
            app.ApplyButton = uibutton(app.SignalpropertiesTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.NumberofperiodsofbaselengthEditFieldLabel = uilabel(app.SignalpropertiesTab);
            app.NumberofperiodsofbaselengthEditFieldLabel.Text = 'Number of periods of base length';
            app.NumberofperiodsofbaselengthEditField = uieditfield(app.SignalpropertiesTab, 'numeric');
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.SignalCheckBox = uicheckbox(app.PlotsTab);
            app.SignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @SignalCheckBoxValueChanged, true);
            app.SignalCheckBox.Text = 'Signal';
            app.SignalCheckBox.Value = true;
            app.STFTCheckBox = uicheckbox(app.PlotsTab);
            app.STFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @STFTCheckBoxValueChanged, true);
            app.STFTCheckBox.Text = 'STFT';
            app.STFTCheckBox.Value = true;
            app.WaveletCheckBox = uicheckbox(app.PlotsTab);
            app.WaveletCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaveletCheckBoxValueChanged, true);
            app.WaveletCheckBox.Text = 'Wavelet';
            app.WaveletCheckBox.Value = true;
            app.FFTCheckBox = uicheckbox(app.PlotsTab);
            app.FFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @FFTCheckBoxValueChanged, true);
            app.FFTCheckBox.Text = 'FFT';
            app.FFTCheckBox.Value = true;
            app.STFTListBoxLabel = uilabel(app.PlotsTab);
            app.STFTListBoxLabel.Text = 'STFT';
            app.STFTListBox = uilistbox(app.PlotsTab);
            app.STFTListBox.Items = {'Image', 'Surface'};
            app.STFTListBox.ValueChangedFcn = createCallbackFcn(app, @STFTListBoxValueChanged, true);
            app.STFTListBox.Value = 'Image';
            app.SignalbuilderTab = uitab(app.TabGroup);
            app.SignalbuilderTab.Title = 'Signal builder';
            app.TabGroup2 = uitabgroup(app.SignalbuilderTab);
            app.AddharmonicTab = uitab(app.TabGroup2);
            app.AddharmonicTab.Title = 'Add harmonic';
            app.AmplitudeEditFieldLabel = uilabel(app.AddharmonicTab);
            app.AmplitudeEditFieldLabel.Text = {'Amplitude'; ''};
            app.AmplitudeEditField = uieditfield(app.AddharmonicTab, 'numeric');
            app.NumberofharmonicEditFieldLabel = uilabel(app.AddharmonicTab);
            app.NumberofharmonicEditFieldLabel.Text = 'Number of harmonic';
            app.NumberofharmonicEditField = uieditfield(app.AddharmonicTab, 'numeric');
            app.AddharmonicButton = uibutton(app.AddharmonicTab, 'push');
            app.AddharmonicButton.ButtonPushedFcn = createCallbackFcn(app, @AddharmonicButtonPushed, true);
            app.AddharmonicButton.Text = 'Add harmonic';
            app.DeleteharmonicTab = uitab(app.TabGroup2);
            app.DeleteharmonicTab.Title = 'Delete harmonic';
            app.NumberofharmonicLabel = uilabel(app.DeleteharmonicTab);
            app.NumberofharmonicLabel.Text = 'Number of harmonic';
            app.Number_of_harmonic = uidropdown(app.DeleteharmonicTab);
            app.Number_of_harmonic.Value = 'Option 2';
            app.DeleteharmonicButton = uibutton(app.DeleteharmonicTab, 'push');
            app.DeleteharmonicButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteharmonicButtonPushed, true);
            app.DeleteharmonicButton.Text = {'Delete harmonic'; ''};
            app.AddsegmentTab = uitab(app.TabGroup2);
            app.AddsegmentTab.Title = 'Add segment';
            app.add_segment = uibutton(app.AddsegmentTab, 'push');
            app.add_segment.ButtonPushedFcn = createCallbackFcn(app, @add_segmentPushed, true);
            app.add_segment.Text = 'Add next segment';
            app.DeletesegmentTab = uitab(app.TabGroup2);
            app.DeletesegmentTab.Title = 'Delete segment';
            app.NumberofsegmentLabel = uilabel(app.DeletesegmentTab);
            app.NumberofsegmentLabel.Text = 'Number of segment';
            app.Number_of_segment = uidropdown(app.DeletesegmentTab);
            app.Delete_segment = uibutton(app.DeletesegmentTab, 'push');
            app.Delete_segment.ButtonPushedFcn = createCallbackFcn(app, @Delete_segmentPushed, true);
            app.Delete_segment.Text = 'Delete segment';
            app.DistortTab = uitab(app.TabGroup2);
            app.DistortTab.Title = 'Distort';
            app.DistortionSliderLabel = uilabel(app.DistortTab);
            app.DistortionSliderLabel.Text = 'Distortion';
            app.DistortionSlider = uislider(app.DistortTab);
            app.DistortButton = uibutton(app.DistortTab, 'push');
            app.DistortButton.ButtonPushedFcn = createCallbackFcn(app, @DistortButtonPushed, true);
            app.DistortButton.Text = 'Distort';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.TabGroup3 = uitabgroup(app.AnalysisTab);
            app.STFTTab = uitab(app.TabGroup3);
            app.STFTTab.Title = 'STFT';
            app.OverlapLabel = uilabel(app.STFTTab);
            app.OverlapLabel.Text = 'Overlap';
            app.Overlap = uislider(app.STFTTab);
            app.Overlap.ValueChangedFcn = createCallbackFcn(app, @OverlapValueChanged, true);
            app.Overlap.Value = 1;
            app.WindowsizeLabel = uilabel(app.STFTTab);
            app.WindowsizeLabel.Text = 'Window size';
            app.Windowsize = uislider(app.STFTTab);
            app.Windowsize.ValueChangedFcn = createCallbackFcn(app, @WindowsizeValueChanged, true);
            app.Overlap_fixed_message = uilabel(app.STFTTab);
            app.Overlap_fixed_message.Text = 'In Surface mode overlap is fixed';
            app.CWTTab = uitab(app.TabGroup3);
            app.CWTTab.Title = 'CWT';
            app.VoicesperoctaveLabel = uilabel(app.CWTTab);
            app.VoicesperoctaveLabel.Text = 'Voices per octave';
            app.VoicesperOctave = uislider(app.CWTTab);
            app.VoicesperOctave.Limits = [4 48];
            app.VoicesperOctave.MajorTicks = [4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48];
            app.VoicesperOctave.MajorTickLabels = {'4', '6', '8', '10', '12', '14', '16', '18', '20', '22', '24', '26', '28', '30', '32', '34', '36', '38', '40', '42', '44', '46', '48'};
            app.VoicesperOctave.ValueChangedFcn = createCallbackFcn(app, @VoicesperOctaveValueChanged, true);
            app.VoicesperOctave.MinorTicks = [];
            app.VoicesperOctave.Value = 4;
            app.TypeofWaveletListBoxLabel = uilabel(app.CWTTab);
            app.TypeofWaveletListBoxLabel.Text = 'Type of Wavelet';
            app.TypeofWaveletListBox = uilistbox(app.CWTTab);
            app.TypeofWaveletListBox.Items = {'amor', 'morse', 'bump', ''};
            app.TypeofWaveletListBox.ValueChangedFcn = createCallbackFcn(app, @TypeofWaveletListBoxValueChanged, true);
            app.TypeofWaveletListBox.Value = 'amor';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.SavesignalasEditFieldLabel = uilabel(app.SaveTab);
            app.SavesignalasEditFieldLabel.Text = 'Save signal as';
            app.SavesignalasEditField = uieditfield(app.SaveTab, 'text');
            app.SavesignalButton = uibutton(app.SaveTab, 'push');
            app.SavesignalButton.ButtonPushedFcn = createCallbackFcn(app, @SavesignalButtonPushed, true);
            app.SavesignalButton.Text = 'Save';
            app.SignalsavedLabel = uilabel(app.SaveTab);
            app.SignalsavedLabel.Text = 'Signal saved!';
            app.LoadsignalTab = uitab(app.TabGroup);
            app.LoadsignalTab.Title = 'Load signal';
            app.ListofsignalsLabel = uilabel(app.LoadsignalTab);
            app.ListofsignalsLabel.Text = 'List of signals';
            app.ListBoxSignals = uilistbox(app.LoadsignalTab);
            app.LoadsignalButton = uibutton(app.LoadsignalTab, 'push');
            app.LoadsignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsignalButtonPushed, true);
            app.LoadsignalButton.Text = 'Load signal';
            app.SignalAxes = uiaxes(app.LeftPanel);
            title(app.SignalAxes, 'Signal')
            xlabel(app.SignalAxes, 'X')
            ylabel(app.SignalAxes, 'Y')
            app.FFTAxes = uiaxes(app.LeftPanel);
            title(app.FFTAxes, 'FFT')
            xlabel(app.FFTAxes, 'X')
            ylabel(app.FFTAxes, 'Y')
            app.STFTAxes = uiaxes(app.LeftPanel);
            title(app.STFTAxes, 'STFT')
            xlabel(app.STFTAxes, 'X')
            ylabel(app.STFTAxes, 'Y')
            app.CWTAxes = uiaxes(app.LeftPanel);
            title(app.CWTAxes, 'Wavelet Transform')
            xlabel(app.CWTAxes, 'X')
            ylabel(app.CWTAxes, 'Y')
            app.CurrentsegmentEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentsegmentEditFieldLabel.Text = 'Current segment';
            app.CurrentsegmentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end